classdef lab2 < matlab.apps.AppBase

    % Properties that correspond to app components
    properties (Access = public)
        UIFigure                  matlab.ui.Figure
        FunctieButtonGroup        matlab.ui.container.ButtonGroup
        predefinitaButton         matlab.ui.control.RadioButton
        interactivButton          matlab.ui.control.RadioButton
        fxDropDownLabel           matlab.ui.control.Label
        fxDropDown                matlab.ui.control.DropDown
        fxEditFieldLabel          matlab.ui.control.Label
        fxEditField               matlab.ui.control.EditField
        aEditFieldLabel           matlab.ui.control.Label
        aEditField                matlab.ui.control.EditField
        bEditFieldLabel           matlab.ui.control.Label
        bEditField                matlab.ui.control.EditField
        AlgoritmButtonGroup       matlab.ui.container.ButtonGroup
        LagrangeButton            matlab.ui.control.RadioButton
        LagrangepeportiuniButton  matlab.ui.control.RadioButton
        SplinecubicButton         matlab.ui.control.RadioButton
        TipulreteleidenoduriButtonGroup  matlab.ui.container.ButtonGroup
        echidistanteButton        matlab.ui.control.RadioButton
        ChebyshevButton           matlab.ui.control.RadioButton
        aleatoareButton           matlab.ui.control.RadioButton
        nEditFieldLabel           matlab.ui.control.Label
        nEditField                matlab.ui.control.EditField
        CalculeazaButton          matlab.ui.control.Button
        UIAxes                    matlab.ui.control.UIAxes
        UIAxes2                   matlab.ui.control.UIAxes
    end

    
    methods (Access = private)
        
        function lagrfn = lagrange(~,xn , yn)
            n = length(xn);
            fntext = "@(x) ";
            
            for i=1:n
                if yn(i) == 0
                    continue;
                end
                
                temp = num2str(yn(i)) + ".*";
                
                numitor = 1;
                for j=1:n
                    if j ~= i
                        temp = temp + "(x + " + num2str(-xn(j)) + ")";
                        
                        if i == n
                            lastElem = n - 1;
                        else
                            lastElem = n;
                        end
                        
                        if j ~= lastElem
                            temp = temp + ".*";
                        end
                        numitor = numitor * (xn(i) - xn(j));
                    end
                end
                temp = temp + "/" + num2str(numitor);
                if i ~= n
                    temp = temp + " + ";
                end
                
                if (numitor ~= 0)
                    fntext = fntext + temp;
                end
            end
                        
            lagrfn = str2func(fntext);
        end
        
        function [xn,yn] = echidistant(~, fn, a, b, n)
            xn = zeros(1,n + 1);
            yn = zeros(1,n + 1);
            
            interval = (b-a)/n;
            
            for i = 0:n
                x = a + interval * i;
                xn(i + 1) = x;
                yn(i + 1) = fn(x);
            end
        end
        
        function [xn,yn] = chebyshev(~, fn, a, b, n)
            function t = getT(i, n) 
                t = cos(pi * (2*i + 1)/(2*n + 2));
            end
            
            xn = zeros(1,n + 1);
            yn = zeros(1,n + 1);
                        
            for i = 0:n
                ti = getT(i, n);
                x = (b + a)/2 + ti * (b-a)/2;
                xn(i + 1) = x;
                yn(i + 1) = fn(x);
            end
        end
    end
    

    % Callbacks that handle component events
    methods (Access = private)

        % Button pushed function: CalculeazaButton
        function CalculeazaButtonPushed(app, event)
            if app.FunctieButtonGroup.SelectedObject == app.predefinitaButton
                fn = str2func("@(x)" + app.fxDropDown.Value);
            else
                fn = str2func("@(x)" + app.fxEditField.Value);
            end
            
            a = str2double(app.aEditField.Value);
            b = str2double(app.bEditField.Value);
            n = str2double(app.nEditField.Value);
            
            if app.TipulreteleidenoduriButtonGroup.SelectedObject == app.echidistanteButton
                [xn,yn] = app.echidistant(fn, a, b, n);
            else
                [xn,yn] = app.chebyshev(fn, a, b, n);
            end
                        
            lagrfn = app.lagrange(xn, yn);
            
            xAxis = linspace(a,b);
            yAxis1 = fn(xAxis);
            
            %plot(app.UIAxes, xAxis, yAxis1);
            
            yAxis2 = lagrfn(xAxis);
            
            plot(app.UIAxes, xAxis, yAxis1, xAxis, yAxis2);
            
            yAxisError = abs(yAxis2 - yAxis1);
            
            plot(app.UIAxes2, xAxis, yAxisError);
            
        end
    end

    % Component initialization
    methods (Access = private)

        % Create UIFigure and components
        function createComponents(app)
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            app.UIFigure = uifigure('Visible', 'off');
            app.UIFigure.Name = 'MATLAB App';
            app.FunctieButtonGroup = uibuttongroup(app.UIFigure);
            app.FunctieButtonGroup.Title = 'Functie';
            app.predefinitaButton = uiradiobutton(app.FunctieButtonGroup);
            app.predefinitaButton.Text = 'predefinita';
            app.predefinitaButton.Value = true;
            app.interactivButton = uiradiobutton(app.FunctieButtonGroup);
            app.interactivButton.Text = 'interactiv';
            app.fxDropDownLabel = uilabel(app.FunctieButtonGroup);
            app.fxDropDownLabel.Text = 'f(x)=';
            app.fxDropDown = uidropdown(app.FunctieButtonGroup);
            app.fxDropDown.Items = {'x.*exp(2.*sin(x))', 'sin(x) + cos(x)', 'sin(pi.*x/6) - cos(x-1)', 'exp(-x) - x.^3 + 8.*cos(4.*x)', 'x.^3 - 5.*arctg(x)'};
            app.fxDropDown.Value = 'x.*exp(2.*sin(x))';
            app.fxEditFieldLabel = uilabel(app.FunctieButtonGroup);
            app.fxEditFieldLabel.Text = 'f(x)=';
            app.fxEditField = uieditfield(app.FunctieButtonGroup, 'text');
            app.fxEditField.Value = 'x.^2';
            app.aEditFieldLabel = uilabel(app.FunctieButtonGroup);
            app.aEditFieldLabel.Text = 'a = ';
            app.aEditField = uieditfield(app.FunctieButtonGroup, 'text');
            app.aEditField.Value = '-2';
            app.bEditFieldLabel = uilabel(app.FunctieButtonGroup);
            app.bEditFieldLabel.Text = 'b = ';
            app.bEditField = uieditfield(app.FunctieButtonGroup, 'text');
            app.bEditField.Value = '2';
            app.AlgoritmButtonGroup = uibuttongroup(app.UIFigure);
            app.AlgoritmButtonGroup.Title = 'Algoritm';
            app.LagrangeButton = uiradiobutton(app.AlgoritmButtonGroup);
            app.LagrangeButton.Text = 'Lagrange';
            app.LagrangeButton.Value = true;
            app.LagrangepeportiuniButton = uiradiobutton(app.AlgoritmButtonGroup);
            app.LagrangepeportiuniButton.Text = 'Lagrange pe portiuni';
            app.SplinecubicButton = uiradiobutton(app.AlgoritmButtonGroup);
            app.SplinecubicButton.Text = 'Spline cubic';
            app.TipulreteleidenoduriButtonGroup = uibuttongroup(app.UIFigure);
            app.TipulreteleidenoduriButtonGroup.Title = 'Tipul retelei de noduri';
            app.echidistanteButton = uiradiobutton(app.TipulreteleidenoduriButtonGroup);
            app.echidistanteButton.Text = 'echidistante';
            app.echidistanteButton.Value = true;
            app.ChebyshevButton = uiradiobutton(app.TipulreteleidenoduriButtonGroup);
            app.ChebyshevButton.Text = 'Chebyshev';
            app.aleatoareButton = uiradiobutton(app.TipulreteleidenoduriButtonGroup);
            app.aleatoareButton.Text = 'aleatoare';
            app.nEditFieldLabel = uilabel(app.TipulreteleidenoduriButtonGroup);
            app.nEditFieldLabel.Text = 'n = ';
            app.nEditField = uieditfield(app.TipulreteleidenoduriButtonGroup, 'text');
            app.nEditField.Value = '3';
            app.CalculeazaButton = uibutton(app.UIFigure, 'push');
            app.CalculeazaButton.ButtonPushedFcn = createCallbackFcn(app, @CalculeazaButtonPushed, true);
            app.CalculeazaButton.Text = 'Calculeaza';
            app.UIAxes = uiaxes(app.UIFigure);
            title(app.UIAxes, 'Graful f-ei')
            xlabel(app.UIAxes, 'X')
            ylabel(app.UIAxes, 'Y')
            zlabel(app.UIAxes, 'Z')
            app.UIAxes2 = uiaxes(app.UIFigure);
            title(app.UIAxes2, 'Eroare')
            xlabel(app.UIAxes2, 'X')
            ylabel(app.UIAxes2, 'Y')
            zlabel(app.UIAxes2, 'Z')
            % Show the figure after all components are created
            app.UIFigure.Visible = 'on';
        end
    end

    % App creation and deletion
    methods (Access = public)

        % Construct app
        function app = lab2

            % Create UIFigure and components
            createComponents(app)

            % Register the app with App Designer
            registerApp(app, app.UIFigure)

            if nargout == 0
                clear app
            end
        end

        % Code that executes before app deletion
        function delete(app)

            % Delete UIFigure when app is deleted
            delete(app.UIFigure)
        end
    end
end